classdef solar_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CloudEditField                 matlab.ui.control.NumericEditField
        CloudLabel                     matlab.ui.control.Label
        DayLenghtminEditField          matlab.ui.control.NumericEditField
        DayLenghtminEditFieldLabel     matlab.ui.control.Label
        SnowmmEditField                matlab.ui.control.NumericEditField
        SnowmmLabel                    matlab.ui.control.Label
        SunLightTimeminEditField       matlab.ui.control.NumericEditField
        SunLightTimeminEditFieldLabel  matlab.ui.control.Label
        RainmmEditField                matlab.ui.control.NumericEditField
        RainmmLabel                    matlab.ui.control.Label
        HumidityEditField              matlab.ui.control.NumericEditField
        HumidityLabel                  matlab.ui.control.Label
        GHIWmEditField                 matlab.ui.control.NumericEditField
        GHIWmLabel                     matlab.ui.control.Label
        PressurehPaEditField           matlab.ui.control.NumericEditField
        TempCLabel                     matlab.ui.control.Label
        TempCSlider                    matlab.ui.control.Slider
        TempCSliderLabel               matlab.ui.control.Label
        WindSpeedmsEditField           matlab.ui.control.NumericEditField
        WindSpeedmsLabel               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ThePredictedEnergyConsumptionisLabel  matlab.ui.control.Label
        EnterTheParametersLabel        matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = predictSolarapp(app, X)
            Mdl = loadLearnerForCoder("solarMdl.mat");
            results = predict(Mdl, X);
        end
        
        function output = convertOutputapp(app, X)
            load variables.mat meandNum stddNum;
            y = (X * stddNum.("Energy delta[Wh]")) + meandNum.("Energy delta[Wh]"); 
            output = num2str(y);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            load variables.mat meandNum stddNum;
            coa = [app.GHIWmEditField.Value app.TempCSlider.Value app.PressurehPaEditField.Value app.HumidityEditField.Value app.WindSpeedmsEditField.Value app.RainmmEditField.Value app.SnowmmEditField.Value app.CloudEditField.Value app.SunLightTimeminEditField.Value app.DayLenghtminEditField.Value];
            c = (coa - meandNum{:, 2:end}) ./ stddNum{:, 2:end};
            app.ThePredictedEnergyConsumptionisLabel.Visible = "on";
            app.ThePredictedEnergyConsumptionisLabel.Enable = "on";
            app.Label.Visible = "on";
            app.Label.Enable = "on";
            app.Label.Text = strcat(convertOutputapp(app,predictSolarapp(app,c)), "Wh");
        end

        % Callback function
        function CLOSEButtonPushed(app, event)

            % app.Panel.Enable = "off";
            % app.Panel.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.EnterTheParametersLabel = uilabel(app.UIFigure);
            app.EnterTheParametersLabel.Text = 'Enter The Parameters';
            app.ThePredictedEnergyConsumptionisLabel = uilabel(app.UIFigure);
            app.ThePredictedEnergyConsumptionisLabel.Enable = 'off';
            app.ThePredictedEnergyConsumptionisLabel.Visible = 'off';
            app.ThePredictedEnergyConsumptionisLabel.Text = {'The Predicted'; 'Energy Consumption is:'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.WindSpeedmsLabel = uilabel(app.UIFigure);
            app.WindSpeedmsLabel.Text = 'Wind Speed (m/s)';
            app.WindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindSpeedmsEditField.Limits = [0 500];
            app.TempCSliderLabel = uilabel(app.UIFigure);
            app.TempCSliderLabel.Text = 'Temp (°C)';
            app.TempCSlider = uislider(app.UIFigure);
            app.TempCLabel = uilabel(app.UIFigure);
            app.TempCLabel.Text = 'Pressure (hPa)';
            app.PressurehPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressurehPaEditField.Limits = [0 2000];
            app.GHIWmLabel = uilabel(app.UIFigure);
            app.GHIWmLabel.Text = 'GHI (W/m².)';
            app.GHIWmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GHIWmEditField.Limits = [0 500];
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity (%)';
            app.HumidityEditField = uieditfield(app.UIFigure, 'numeric');
            app.HumidityEditField.Limits = [0 100];
            app.RainmmLabel = uilabel(app.UIFigure);
            app.RainmmLabel.Text = 'Rain (mm)';
            app.RainmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RainmmEditField.Limits = [0 90];
            app.SunLightTimeminEditFieldLabel = uilabel(app.UIFigure);
            app.SunLightTimeminEditFieldLabel.Text = 'SunLight Time (min)';
            app.SunLightTimeminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SunLightTimeminEditField.Limits = [0 1440];
            app.SnowmmLabel = uilabel(app.UIFigure);
            app.SnowmmLabel.Text = 'Snow (mm)';
            app.SnowmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SnowmmEditField.Limits = [0 Inf];
            app.DayLenghtminEditFieldLabel = uilabel(app.UIFigure);
            app.DayLenghtminEditFieldLabel.Text = 'Day Lenght (min)';
            app.DayLenghtminEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayLenghtminEditField.Limits = [0 1440];
            app.CloudLabel = uilabel(app.UIFigure);
            app.CloudLabel.Text = 'Cloud (%)';
            app.CloudEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudEditField.Limits = [0 100];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solar_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end